classdef Client < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        ClientButton          matlab.ui.control.Button
        RecordButton          matlab.ui.control.Button
        SendButton            matlab.ui.control.Button
        UIAxes                matlab.ui.control.UIAxes
        StopButton            matlab.ui.control.Button
        Label                 matlab.ui.control.Label
        TextArea              matlab.ui.control.TextArea
        ClassificationButton  matlab.ui.control.Button
        EditField             matlab.ui.control.EditField
    end

    properties (Access = private)
        tcpipClient;
        webcamObject = webcam();
        imageObject;
        recording = 1;
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.StopButton.Visible = 'off';
            app.SendButton.Visible = 'off';
            app.ClassificationButton.Visible = 'off';
            app.TextArea.Visible = 'off';
            app.Label.Visible = 'off';
            app.UIAxes.Visible = 'off';
        end

        % Button pushed function: ClientButton
        function ClientButtonPushed(app, event)
            delete(instrfindall);
            %192.168.1.114
            %192.168.1.95
            ip = app.EditField.Value;
            if (isempty(ip))
                ip = 'localhost';
            end
            app.tcpipClient = tcpip(ip, 30000, 'NetworkRole', 'client');
            
            set(app.tcpipClient, 'OutputBufferSize', 50000000);
            set(app.tcpipClient,'Timeout',30);
            
            fopen(app.tcpipClient);
            
            app.EditField.Visible = 'off';
            app.ClientButton.Visible = 'off';
            app.SendButton.Visible = 'on';
            app.ClassificationButton.Visible = 'on';
        end

        % Button pushed function: SendButton
        function SendButtonPushed(app, event)
            [file, path] = uigetfile({'*.avi;*.mov;*.wmv;*.mp4'});
            if isequal(file,0)
                disp('Canceled')
            else
                inputFilePath = fullfile(path,file);
                
                videoFReader = vision.VideoFileReader(inputFilePath);
                frame = videoFReader();
                frame = imresize(frame, [360 640]);
                data = im2uint8(frame);
                arrSize = size(data);
                fwrite(app.tcpipClient, arrSize, 'int');
                              
                vid = VideoReader(inputFilePath);
                n = vid.NumberOfFrames - 1;
                
                count = 0;
                
                app.SendButton.Visible = 'off';
                app.ClassificationButton.Visible = 'off';
                app.TextArea.Visible = 'on';
                drawnow
                
                while ~isDone(videoFReader)
                    
                   %if (mod(count, 300) == 299)
                   %    textLabel = sprintf('Sent frame %d out of %d', count, n);
                   %    app.TextArea.Value = textLabel;
                   %end
                   
                   %s = whos('data');
                   %if (s.size ~= arrSize)
                   %    disp('Error');
                   %end
                   
                   fwrite(app.tcpipClient, data(:), 'uint8');
                   count = count + 1;
                   textLabel = sprintf('Sent frame %d out of %d', count, n);
                   app.TextArea.Value = textLabel;
                   %drawnow
                   disp(textLabel);
                   
                   frame = step(videoFReader);
                   frame = imresize(frame, [360 640]);
                   data = im2uint8(frame);
                end
                
                fwrite(app.tcpipClient, zeros(size(data(:))), 'uint8'); %Stop
                
                app.ClassificationButton.Visible = 'on';
            end
        end

        % Button pushed function: RecordButton
        function RecordButtonPushed(app, event)
            app.RecordButton.Visible = 'off';
            
            app.imageObject = image(app.UIAxes);
            axis(app.UIAxes,'ij')
            res = split(app.webcamObject.Resolution,'x');
            app.UIAxes.XLim = [0,str2double(res{1})];
            app.UIAxes.YLim = [0,str2double(res{2})];
            app.webcamObject.preview(app.imageObject);
            %preview(app.webcamObject);
 
            video_object = VideoWriter('getframes.mp4', 'MPEG-4');
            %video_object.CompressionRatio
            %video_object.FileFormat
            %video_object.Duration
            video_object.FrameRate = 3;
            %video_object.Height = 360;
            %video_object.Quality
            %video_object.VideoFormat
            %video_object.Width = 640;
            open(video_object);
            app.StopButton.Visible = 'on';
            while (app.recording == 1)
                writeVideo(video_object, snapshot(app.webcamObject));
            end
            close(video_object);
            
            %closePreview(app.webcamObject);
            app.webcamObject.closePreview;
            
            app.imageObject.Visible = 'off';
            
            app.RecordButton.Visible = 'on';
            app.StopButton.Visible = 'off';
            app.recording = 1;
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.recording = 0;
        end

        % Callback function
        function TestButtonPushed(app, event)
            zip('compVid','Film2.mov');
        end

        % Button pushed function: ClassificationButton
        function ClassificationButtonPushed(app, event)
            app.SendButton.Visible = 'off';
            
            while (get(app.tcpipClient, 'BytesAvailable') <= 0)
            end
            
            labelSize = fread(app.tcpipClient, 2, 'int');
            label = fread(app.tcpipClient, max(labelSize), 'int');
            
            str = sprintf(' %d',label);
            str = str(2:end);
            textLabel = str;
            app.TextArea.Visible = 'on';
            app.TextArea.Value = textLabel;
                   
            fclose(app.tcpipClient);
            
            app.ClientButton.Visible = 'on';
            app.EditField.Visible = 'on';
            app.ClassificationButton.Visible = 'off';
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.ClientButton = uibutton(app.UIFigure, 'push');
            app.ClientButton.ButtonPushedFcn = createCallbackFcn(app, @ClientButtonPushed, true);
            app.ClientButton.Text = 'Client';
            app.RecordButton = uibutton(app.UIFigure, 'push');
            app.RecordButton.ButtonPushedFcn = createCallbackFcn(app, @RecordButtonPushed, true);
            app.RecordButton.Text = 'Record';
            app.SendButton = uibutton(app.UIFigure, 'push');
            app.SendButton.ButtonPushedFcn = createCallbackFcn(app, @SendButtonPushed, true);
            app.SendButton.Text = 'Send';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.TextArea = uitextarea(app.UIFigure);
            app.ClassificationButton = uibutton(app.UIFigure, 'push');
            app.ClassificationButton.ButtonPushedFcn = createCallbackFcn(app, @ClassificationButtonPushed, true);
            app.ClassificationButton.Text = 'Classification';
            app.EditField = uieditfield(app.UIFigure, 'text');
        end
    end

    methods (Access = public)

        % Construct app
        function app = Client

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end