classdef Client_UDP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        ClientButton   matlab.ui.control.Button
        RecordButton   matlab.ui.control.Button
        SendButton     matlab.ui.control.Button
        UIAxes         matlab.ui.control.UIAxes
        StopButton     matlab.ui.control.Button
        TextAreaLabel  matlab.ui.control.Label
        TextArea       matlab.ui.control.TextArea
        ResetButton    matlab.ui.control.Button
    end

    
    properties (Access = private)
        udpClient;
        webcamObject = webcam();
        imageObject;
        recording = 1;
    end
    
    properties (Access = public)
        %mainWindow matlab.ui.Figure
        %frameProg matlab.ui.control.TextArea
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.StopButton.Visible = 'off';
            app.TextArea.Visible = 'off';
            app.TextAreaLabel.Visible = 'off';
        end

        % Button pushed function: ClientButton
        function ClientButtonPushed(app, event)
            delete(instrfindall);
            
            %%Define computer-specific variables
            % Modify these values to be those of your first computer:
            ipS = '127.0.0.1';   portS = 9090;
            % Modify these values to be those of your second computer:
            ipC = '127.0.0.1';  portC = 9091;
            %%Create UDP Object
            app.udpClient = udp(ipS,portS,'LocalPort',portC);
            
            set(app.udpClient, 'OutputBufferSize', 50000000);
            set(app.udpClient, 'InputBufferSize', 50000000);
            %app.udpClient.DatagramTerminateMode = 'ON';
            set(app.udpClient, 'OutputDatagramPacketSize', 65535);
            set(app.udpClient, 'InputDatagramPacketSize', 65535);
            set(app.udpClient, 'Timeout', 30);
            
            fopen(app.udpClient);
            
            %app.udpClient.ReadAsyncMode = 'manual';
            %readasync(app.udpClient);
            
            app.ClientButton.Visible = 'off';
        end

        % Button pushed function: SendButton
        function SendButtonPushed(app, event)
            %Upload video
            [file, path] = uigetfile({'*.avi;*.mov;*.wmv;*.mp4'});
            if isequal(file,0)
                disp('Canceled')
            else
                inputFilePath = fullfile(path,file);
                
                videoFReader = vision.VideoFileReader(inputFilePath);
                frame = videoFReader();
                data = im2uint8(frame);
                data2 = im2double(frame);
                arrSize = size(data);
                product = arrSize(1) * arrSize(2) * arrSize(3);
                fwrite(app.udpClient, arrSize, 'int');
                
                vid = VideoReader(inputFilePath);
                n = vid.NumberOfFrames - 1;
                
                count = 0;
                
                app.TextArea.Visible = 'on';
                app.SendButton.Visible = 'off';
                
                DPS = 57600;
                while ~isDone(videoFReader)
                    %s = whos('data');

                    %if (s.bytes ~= 22118400)
                    %    disp('Error');
                    %end
                    
                    for i = 1:(product/DPS)
                        fwrite(app.udpClient, data(((i-1)*DPS)+1:i*DPS), 'uint8');
                        %fromServer = fread(app.udpClient, app.udpClient.InputDatagramPacketSize,'int');
                        %if (isequal(fromServer, [1, 1]))
                        %    i = i - 1;
                        %end
                    end
                    count = count + 1;
                    textLabel = sprintf('Sent frame %d out of %d', count, n);
                    app.TextArea.Value = textLabel;
                    disp(textLabel);
                    
                    aa = data(:);
                    rs = reshape(aa, arrSize(1), arrSize(2)*arrSize(3));
                    reshapedData = reshape(rs,arrSize(1),arrSize(2),arrSize(3));
                    
                    frame = step(videoFReader);
                    data = im2uint8(frame);
                end
                
                fwrite(app.udpClient, zeros(DPS, 1), 'uint8'); %Stop
                
                app.ClientButton.Visible = 'on';
                app.SendButton.Visible = 'off';
                
                fclose(app.udpClient);
            end
        end

        % Button pushed function: RecordButton
        function RecordButtonPushed(app, event)
            app.RecordButton.Visible = 'off';
            
            app.imageObject = image(app.UIAxes);
            axis(app.UIAxes,'ij')
            res = split(app.webcamObject.Resolution,'x');
            app.UIAxes.XLim = [0,str2double(res{1})];
            app.UIAxes.YLim = [0,str2double(res{2})];
            app.webcamObject.preview(app.imageObject);
            %preview(app.webcamObject);
 
            video_object = VideoWriter('getframes.avi');
            %video_object.FileFormat
            %video_object.Duration
            %video_object.Filename
            %video_object.FrameCount
            %video_object.FrameRate
            %video_object.Path
            %video_object.VideoFormat
            open(video_object);
            app.StopButton.Visible = 'on';
            while (app.recording == 1)
                writeVideo(video_object, snapshot(app.webcamObject));
            end
            close(video_object);
            
            %closePreview(app.webcamObject);
            app.webcamObject.closePreview;
            
            app.imageObject.Visible = 'off';
            
            app.RecordButton.Visible = 'on';
            app.StopButton.Visible = 'off';
            app.recording = 1;
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.recording = 0;
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            fclose(app.udpClient);
            delete(app.udpClient);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.ClientButton = uibutton(app.UIFigure, 'push');
            app.ClientButton.ButtonPushedFcn = createCallbackFcn(app, @ClientButtonPushed, true);
            app.ClientButton.Text = 'Client';
            app.RecordButton = uibutton(app.UIFigure, 'push');
            app.RecordButton.ButtonPushedFcn = createCallbackFcn(app, @RecordButtonPushed, true);
            app.RecordButton.Text = 'Record';
            app.SendButton = uibutton(app.UIFigure, 'push');
            app.SendButton.ButtonPushedFcn = createCallbackFcn(app, @SendButtonPushed, true);
            app.SendButton.Text = 'Send';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.TextAreaLabel = uilabel(app.UIFigure);
            app.TextAreaLabel.Text = 'Text Area';
            app.TextArea = uitextarea(app.UIFigure);
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Client_UDP

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end