classdef Live_Client < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        ClientButton  matlab.ui.control.Button
        StreamButton  matlab.ui.control.Button
        StopButton    matlab.ui.control.Button
        UIAxes        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        tcpipClient;
        webcamObject = webcam();
        imageObject;
        streaming = 1;
    end
    

    methods (Access = private)

        % Button pushed function: ClientButton
        function ClientButtonPushed(app, event)
            app.tcpipClient = tcpip('localhost', 30000, 'NetworkRole', 'client');
            
            set(app.tcpipClient, 'OutputBufferSize', 50000000);
            set(app.tcpipClient,'Timeout',30);
            
            fopen(app.tcpipClient);
            
            app.ClientButton.Visible = 'off';
        end

        % Button pushed function: StreamButton
        function StreamButtonPushed(app, event)
            app.imageObject = image(app.UIAxes);
            axis(app.UIAxes,'ij')
            res = split(app.webcamObject.Resolution,'x');
            app.UIAxes.XLim = [0,str2double(res{1})];
            app.UIAxes.YLim = [0,str2double(res{2})];
            app.webcamObject.preview(app.imageObject);
            %preview(app.webcamObject);
            
            app.StreamButton.Visible = 'off';
            app.StopButton.Visible = 'on';
            while (app.streaming == 1)
                frame = snapshot(app.webcamObject);
                data = im2double(frame);
                s = whos('data');
                s.size; %720 1280 3
                s.bytes;
                %tet = get(app.tcpipClient,{'OutputBufferSize','BytesToOutput'});
                if (s.bytes ~= 22118400)
                    disp('Error');
                end
                fwrite(app.tcpipClient, data(:), 'double');
                %disp('Loop');
                %pause(0.4)
            end
            
            %closePreview(app.webcamObject);
            app.webcamObject.closePreview;
            
            app.imageObject.Visible = 'off';
            
            app.RecordButton.Visible = 'on';
            app.StopButton.Visible = 'off';
            app.streaming = 1;
            
            fwrite(app.tcpipClient, zeros(1,1), 'double'); %Stop
            
            fclose(app.tcpipClient);
            
            app.ClientButton.Visible = 'on';
            app.StreamButton.Visible = 'on';
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.streaming = 0;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.ClientButton = uibutton(app.UIFigure, 'push');
            app.ClientButton.ButtonPushedFcn = createCallbackFcn(app, @ClientButtonPushed, true);
            app.ClientButton.Text = 'Client';
            app.StreamButton = uibutton(app.UIFigure, 'push');
            app.StreamButton.ButtonPushedFcn = createCallbackFcn(app, @StreamButtonPushed, true);
            app.StreamButton.Text = 'Stream';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
        end
    end

    methods (Access = public)

        % Construct app
        function app = Live_Client

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end