classdef Live_Server < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        ServerButton  matlab.ui.control.Button
        CloseButton   matlab.ui.control.Button
        FeedButton    matlab.ui.control.Button
    end

    
    properties (Access = private)
        tcpipServer;
        %DataReceived = [];
    end
    
    methods (Access = private)
        
        function [runLoop, t2, processingTime, pointTracker, oldPoints,...
                bboxPoints, bbox, idxFrame, LabAvg, time, Tx,...
                videoPlayer] = RTM(~, runLoop, t2, processingTime,...
                pointTracker, videoFrame, oldPoints, bboxPoints, bbox,...
                idxFrame, LabAvg, time, Tx, videoPlayer)
            while (runLoop && t2 < processingTime) % idxFrame< 10000
                % Track the points. Note that some points may be lost.
                [points, isFound] = step(pointTracker, videoFrame);
                visiblePoints = points(isFound, :);
                oldInliers = oldPoints(isFound, :);
                
                if (size(visiblePoints, 1) >= 8) % need at least 2 points
                    % Estimate the geometric transformation between the old points
                    % and the new points and eliminate outliers
                    [xform, ~, visiblePoints] = estimateGeometricTransform(...
                        oldInliers, visiblePoints, 'similarity', 'MaxDistance', 4);
                    
                    % Apply the transformation to the bounding box points
                    bboxPoints = transformPointsForward(xform, bboxPoints);
                    % bboxPoints
                    
                    bboxPolygon = reshape(bboxPoints', 1, []);
                    % newly created function 29/09/2016
                    [newbboxPolygon,ROIbox,ROI] = adjPolygon(bboxPolygon,videoFrame);
                    
                    videoFrame = insertShape(videoFrame, 'Polygon', newbboxPolygon, 'LineWidth', 3, 'Color','green');
                    videoFrame = insertShape(videoFrame, 'Polygon', ROIbox, 'LineWidth', 5, 'Color','magenta');

                    % Reset the points
                    oldPoints = visiblePoints;
                    setPoints(pointTracker, oldPoints);
                end
                
                if (size(visiblePoints, 1) < 8) % need at least 2 points
                    bboxPoints = bbox2points(bbox(1, :));
                    points = detectMinEigenFeatures(rgb2gray(videoFrame), 'ROI', bbox);
                    pointTracker = vision.PointTracker('MaxBidirectionalError', 2);
                    points = points.Location;
                    initialize(pointTracker, points, videoFrame);
                    oldPoints = points;
                end
                
                fImg = ROI; %imshow(fImg);
                LabImg = rgbImg2LabImg(fImg);
                for ch = 1:3
                    labCh = LabImg (:,:,ch);
                    LabAvg(ch, idxFrame) = mean(labCh(:));
                end % for
                
                % PP count start when conditions fulfilled
                if ((idxFrame >= 100) && mod(idxFrame,6) == 0)
                    rgb = LabAvg(2:3,:);
                    
                    [HR1, IBI1] = HRibi_fft(rgb,idxFrame);
                    
                    time = time + 250;
                end
                
                % Heart rate
                videoFrame = insertText(videoFrame, [Tx 40],'IBI=', 'FontSize', 18, 'BoxColor', 'cyan', 'BoxOpacity', 0.4);
                videoFrame = insertText(videoFrame, [Tx+50 40],num2str(IBI1,'%0.2f'), 'FontSize', 18, 'BoxColor', 'cyan', 'BoxOpacity', 0.4);
                % Heart rate
                
                videoFrame = insertText(videoFrame, [Tx 10],'HR=', 'FontSize', 18, 'BoxColor', 'cyan', 'BoxOpacity', 0.4);
                videoFrame = insertText(videoFrame, [Tx+50 10],num2str(HR1,'%0.2f'), 'FontSize', 18, 'BoxColor', 'cyan', 'BoxOpacity', 0.4);

                idxFrame = idxFrame + 1;
                t2 = toc;
                
                videoFrame = insertText(videoFrame, [5 40],'Processing Time=', 'FontSize', 18, 'BoxColor', 'cyan', 'BoxOpacity', 0.4);
                videoFrame = insertText(videoFrame, [170 40],num2str(t2,'%0.2f'), 'FontSize', 18, 'BoxColor', 'cyan', 'BoxOpacity', 0.4);
                videoFrame = insertText(videoFrame, [220 40],'Sec.', 'FontSize', 18, 'BoxColor', 'cyan', 'BoxOpacity', 0.4);
                
                framerate = floor((idxFrame/t2));
                videoFrame = insertText(videoFrame, [5 10],'Current Frame Rate=', 'FontSize', 18, 'BoxColor', 'cyan', 'BoxOpacity', 0.4);
                videoFrame = insertText(videoFrame, [190 10],num2str(framerate,'%0.0f'), 'FontSize', 18, 'BoxColor', 'cyan', 'BoxOpacity', 0.4);
                videoFrame = insertText(videoFrame, [220 10],'fps', 'FontSize', 18, 'BoxColor', 'cyan', 'BoxOpacity', 0.4);
                step(videoPlayer, videoFrame);
            end
        end
    end
    

    methods (Access = private)

        % Button pushed function: ServerButton
        function ServerButtonPushed(app, event)
            app.tcpipServer = tcpip('0.0.0.0', 30000, 'NetworkRole', 'Server');
            
            set(app.tcpipServer,'InputBufferSize', 60000000);
            set(app.tcpipServer,'Timeout',30);
            
            app.ServerButton.Visible = 'off';
            app.CloseButton.Visible = 'on';
            
            fopen(app.tcpipServer);
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            fclose(app.tcpipServer);
            delete(app.tcpipServer);
            clear tcpipServer
            
            app.ServerButton.Visible = 'on';
        end

        % Button pushed function: FeedButton
        function FeedButtonPushed(app, event)
            app.FeedButton.Visible = 'off';
            
            first = 1;
            
            % Create the face detector object.
            faceDetector = vision.CascadeObjectDetector();
            
            % Create a point tracker and enable the bidirectional error constraint to
            % make it more robust in the presence of noise and clutter.
            pointTracker = vision.PointTracker('MaxBidirectionalError', 2);
            
            % Initialization
            idxFrame = 1;
            LabAvg = zeros(3, idxFrame);
            time = 0;
            processingTime = 1000; % second
            t2 = 0;
            tic;
            runLoop = true;
            
            tt = zeros(100,100);
            s = whos('tt');
            %pause(0.2);
            while (s.bytes > 100)
                while (get(app.tcpipServer, 'BytesAvailable') > 0)
                    %2764800
                    %23000000
                    rawData = fread(app.tcpipServer, 2764800,'double');
                    %app.DataReceived = [app.DataReceived; rawData];
                    s = whos('rawData');
                    s.size; %720 1280 3
                    s.bytes;
                    if (s.bytes ~= 22118400)
                        disp('Error');
                    else
                        rs = reshape(rawData, 720, 1280*3);
                        videoFrame = reshape(rs,720, 1280,3);
                        
                        if (first == 1) %Do once
                            first = 0;
                            
                            bbox = step(faceDetector, videoFrame);
                            if (isempty(bbox) == 1)
                                bbox = [270 291 127 127];
                            end
                            
                            if (size(bbox,1) > 1)
                                bbox = bbox(2,:);
                            end
                            
                            % Draw the returned bounding box around the detected face.
                            videoFrame = insertShape(videoFrame, 'Rectangle', bbox);
                            
                            % Convert the first box into a list of 4 points
                            % This is needed to be able to visualize the rotation of the object.
                            bboxPoints = bbox2points(bbox(1, :));
                            
                            % Detect feature points in the face region.
                            points = detectMinEigenFeatures(rgb2gray(videoFrame), 'ROI', bbox);
                            
                            % Initialize the tracker with the initial point locations and the initial
                            % video frame.
                            points = points.Location;
                            initialize(pointTracker, points, videoFrame);
                            
                            videoPlayer = vision.DeployableVideoPlayer; %('Position',[100 100 [size(videoFrame, 2), size(videoFrame, 1)]+30]);
                            
                            % Make a copy of the points to be used for computing the geometric
                            % transformation between the points in the previous and the current frames
                            oldPoints = points;
                            
                            Tx = size(videoFrame,2)/2; % text position
                        else
                        [runLoop, t2, processingTime, pointTracker,...
                            oldPoints, bboxPoints, bbox, idxFrame,...
                            LabAvg, time, Tx, videoPlayer] = RTM(app,...
                            runLoop, t2, processingTime, pointTracker,...
                            videoFrame, oldPoints, bboxPoints, bbox,...
                            idxFrame, LabAvg, time, Tx, videoPlayer);         
                        end
                    end
                end
            end           
            release(videoPlayer);
            release(pointTracker);
            disp('done...');
            
            app.FeedButton.Visible = 'on';
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.ServerButton = uibutton(app.UIFigure, 'push');
            app.ServerButton.ButtonPushedFcn = createCallbackFcn(app, @ServerButtonPushed, true);
            app.ServerButton.Text = 'Server';
            app.CloseButton = uibutton(app.UIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.FeedButton = uibutton(app.UIFigure, 'push');
            app.FeedButton.ButtonPushedFcn = createCallbackFcn(app, @FeedButtonPushed, true);
            app.FeedButton.Text = 'Feed';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Live_Server

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end