classdef Server < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        ServerButton   matlab.ui.control.Button
        AnalyzeButton  matlab.ui.control.Button
        ReadButton     matlab.ui.control.Button
        Label          matlab.ui.control.Label
        TextArea       matlab.ui.control.TextArea
    end

    properties (Access = private)
        tcpipServer;
    end    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.TextArea.Visible = 'off';
            app.Label.Visible = 'off';
            app.ReadButton.Visible = 'off';
            app.AnalyzeButton.Visible = 'off';
        end

        % Button pushed function: ServerButton
        function ServerButtonPushed(app, event)
            delete(instrfindall);
            
            app.tcpipServer = tcpip('0.0.0.0', 30000, 'NetworkRole', 'Server');
            
            set(app.tcpipServer,'InputBufferSize', 60000000);
            set(app.tcpipServer,'Timeout',30);
            
            app.ServerButton.Visible = 'off';
            app.ReadButton.Visible = 'on';
            app.AnalyzeButton.Visible = 'on';
            
            fopen(app.tcpipServer);
        end

        % Callback function
        function CloseButtonPushed(app, event)
            
        end

        % Button pushed function: ReadButton
        function ReadButtonPushed(app, event)
            tic;
            
            video_object = VideoWriter('receivedvid');
            %video_object.FrameRate = 3;
            open(video_object);
            
            app.ReadButton.Visible = 'off';
            app.AnalyzeButton.Visible = 'off';
            app.TextArea.Visible = 'on';
            drawnow
            
            count = 0;
            
            arrSize = fread(app.tcpipServer, 3, 'int');
            product = arrSize(1) * arrSize(2) * arrSize(3);
            
            rawData = 1;
            empty = zeros(product, 1);
            while (isequal(rawData, empty) == 0)
                while (get(app.tcpipServer, 'BytesAvailable') > 0)
                    
                    %if (mod(count, 300) == 299)
                    %    textLabel = sprintf('Received %d frames', count);
                    %    app.TextArea.Value = textLabel;
                    %end
                    
                    rawData = fread(app.tcpipServer, product,'uint8');
                    rawData = uint8(rawData);
                    
                    
                    if (isequal(rawData, empty) == 0)
                        rs = reshape(rawData, arrSize(1), arrSize(2) * arrSize(3));
                        reshapedData = reshape(rs,arrSize(1), arrSize(2), arrSize(3));
                        writeVideo(video_object, reshapedData);
                        
                        count = count + 1;
                        textLabel = sprintf('Received %d frames', count);
                        app.TextArea.Value = textLabel;
                        %drawnow
                        disp(textLabel);
                    end
                end
            end    
            close(video_object);
            
            toc
            
            app.AnalyzeButton.Visible = 'on';
        end

        % Button pushed function: AnalyzeButton
        function AnalyzeButtonPushed(app, event)
            [file, path] = uigetfile({'*.avi;*.mov;*.wmv;*.mp4'});
            if isequal(file,0)
                disp('Canceled')
            else
                
                app.AnalyzeButton.Visible = 'off';
                
                inputFilePath = fullfile(path,file);
                label = detectAndTrackFacesOffline(inputFilePath);
                %label = [1];
                            
                fwrite(app.tcpipServer, size(label), 'int');
                fwrite(app.tcpipServer, label, 'int');
                
                fclose(app.tcpipServer);
                delete(app.tcpipServer);
                clear tcpipServer
                
                app.ServerButton.Visible = 'on';               
            end
        end

        % Callback function
        function TestButtonPushed(app, event)
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.ServerButton = uibutton(app.UIFigure, 'push');
            app.ServerButton.ButtonPushedFcn = createCallbackFcn(app, @ServerButtonPushed, true);
            app.ServerButton.Text = 'Server';
            app.AnalyzeButton = uibutton(app.UIFigure, 'push');
            app.AnalyzeButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeButtonPushed, true);
            app.AnalyzeButton.Text = 'Analyze';
            app.ReadButton = uibutton(app.UIFigure, 'push');
            app.ReadButton.ButtonPushedFcn = createCallbackFcn(app, @ReadButtonPushed, true);
            app.ReadButton.Text = 'Read';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.TextArea = uitextarea(app.UIFigure);
        end
    end

    methods (Access = public)

        % Construct app
        function app = Server

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end