classdef Server_UDP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        ServerButton   matlab.ui.control.Button
        AnalyzeButton  matlab.ui.control.Button
        CloseButton    matlab.ui.control.Button
        ReadButton     matlab.ui.control.Button
        TestButton     matlab.ui.control.Button
        TextAreaLabel  matlab.ui.control.Label
        TextArea       matlab.ui.control.TextArea
        ResetButton    matlab.ui.control.Button
    end

    
    properties (Access = private)
        udpServer;
    end
    
    methods (Access = private)
        
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.CloseButton.Visible = 'off';
            app.TextArea.Visible = 'off';
            app.TextAreaLabel.Visible = 'off';
        end

        % Button pushed function: ServerButton
        function ServerButtonPushed(app, event)
            delete(instrfindall);
            
            %%Define computer-specific variables
            % Modify these values to be those of your first computer.
            ipS = '127.0.0.1';   portS = 9090;
            % Modify these values to be those of your second computer.
            ipC = '127.0.0.1';  portC = 9091;

            app.udpServer = udp(ipC,portC,'LocalPort',portS);
            
            set(app.udpServer, 'InputBufferSize', 50000000);
            set(app.udpServer, 'OutputBufferSize', 50000000);
            %app.udpServer.DatagramTerminateMode = 'ON';
            set(app.udpServer, 'InputDatagramPacketSize', 65535);
            set(app.udpServer, 'OutputDatagramPacketSize', 65535);
            set(app.udpServer, 'Timeout', 30);
            
            app.ServerButton.Visible = 'off';
            app.CloseButton.Visible = 'on';
            
            fopen(app.udpServer);
            
            %app.udpServer.ReadAsyncMode = 'manual';
            %readasync(app.udpServer);
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            fclose(app.tcpipServer);
            delete(app.tcpipServer);
            clear udpServer
            
            app.ServerButton.Visible = 'on';
        end

        % Button pushed function: ReadButton
        function ReadButtonPushed(app, event)
            app.ReadButton.Visible = 'off';
            
            video_object = VideoWriter('receivedvid');
            open(video_object);
            
            app.ReadButton.Visible = 'off';
            app.TextArea.Visible = 'on';
            
            count = 0;
            i = 1;
            
            arrSize = fread(app.udpServer, 3, 'int');
            product = arrSize(1) * arrSize(2) * arrSize(3);
            
            DPS = 57600;
            
            rawData = 1;
            empty = zeros(DPS, 1);
            allRawData = zeros(product, 1);
            while (isequal(rawData, empty) == 0)
                while (get(app.udpServer, 'BytesAvailable') > 0)
                    %102400
                    rawData = fread(app.udpServer, app.udpServer.InputDatagramPacketSize,'uint8');
                    rawData = uint8(rawData);
                    
                    if (size(rawData) ~= DPS)
                        disp('Error')
                        %fwrite(app.udpServer, [2, 2], 'int');
                    else
                        pos1 = (i-1) * DPS;
                        pos2 = i * DPS;
                        allRawData((pos1 + 1):pos2) = rawData;
                        
                        %s = whos('rawData');
                        
                        i = i + 1;
                        
                        %fwrite(app.udpServer, [1, 1], 'int');
                        if (i == product/DPS)
                            allRawData = uint8(allRawData);
                            rs = reshape(allRawData, arrSize(1), arrSize(2)*arrSize(3));
                            reshapedData = reshape(rs,arrSize(1),arrSize(2),arrSize(3));
                            writeVideo(video_object, reshapedData);
                            
                            count = count + 1;
                            i = 1;
                            
                            textLabel = sprintf('Received %d frames', count);
                            app.TextArea.Value = textLabel;
                            disp(textLabel);
                        end
                    end
                end
            end
            
            close(video_object);
            
            fclose(app.udpServer);
            delete(app.udpServer);
            clear tcpipServer
            
            app.ServerButton.Visible = 'on';
            
            disp('Finished');
        end

        % Button pushed function: AnalyzeButton
        function AnalyzeButtonPushed(app, event)
            [file, path] = uigetfile('*.avi');
            if isequal(file,0)
                disp('Canceled')
            else
                app.AnalyzeButton.Visible = 'off';
                inputFilePath = fullfile(path,file);
                %calcHRIBI(app, inputFilePath);
                detectAndTrackFacesOffline(inputFilePath);
                app.AnalyzeButton.Visible = 'on';
            end
        end

        % Button pushed function: TestButton
        function TestButtonPushed(app, event)
            [file, path] = uigetfile('*.avi','File Selector');
            inputFilePath = fullfile(path,file);
            
            videoFReader = vision.VideoFileReader(inputFilePath);
            videoPlayer = vision.DeployableVideoPlayer;
            while ~isDone(videoFReader)
                frame = step(videoFReader);
                step(videoPlayer,frame);
            end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            fclose(app.udpServer);
            delete(app.udpServer);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.ServerButton = uibutton(app.UIFigure, 'push');
            app.ServerButton.ButtonPushedFcn = createCallbackFcn(app, @ServerButtonPushed, true);
            app.ServerButton.Text = 'Server';
            app.AnalyzeButton = uibutton(app.UIFigure, 'push');
            app.AnalyzeButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeButtonPushed, true);
            app.AnalyzeButton.Text = 'Analyze';
            app.CloseButton = uibutton(app.UIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.ReadButton = uibutton(app.UIFigure, 'push');
            app.ReadButton.ButtonPushedFcn = createCallbackFcn(app, @ReadButtonPushed, true);
            app.ReadButton.Text = 'Read';
            app.TestButton = uibutton(app.UIFigure, 'push');
            app.TestButton.ButtonPushedFcn = createCallbackFcn(app, @TestButtonPushed, true);
            app.TestButton.Text = 'Test';
            app.TextAreaLabel = uilabel(app.UIFigure);
            app.TextAreaLabel.Text = 'Text Area';
            app.TextArea = uitextarea(app.UIFigure);
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Server_UDP

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end